classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        DetectDropDown                 matlab.ui.control.DropDown
        SelectthenetworkDropDown_2Label  matlab.ui.control.Label
        DetectButton                   matlab.ui.control.Button
        PredictDropDown                matlab.ui.control.DropDown
        SelectthenetworkDropDownLabel  matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Tab2                           matlab.ui.container.Tab
        ChooseDatasetDropDown          matlab.ui.control.DropDown
        ChooseDatasetDropDownLabel     matlab.ui.control.Label
        TrainButton                    matlab.ui.control.Button
        TestButton                     matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ChoosethenetworkDropDown_2Label  matlab.ui.control.Label
        ChoosethenetworkLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Define properties here to store values
        selectedNetwork
        type
    end
    
    methods (Access = private)
        
        function PredictPushed(app)

            app.selectedNetwork = app.PredictDropDown.Value;
            app.type = "predict";
            app.TabGroup.SelectedTab = app.Tab2;

        end
        
        function DetectPushed(app)

            app.selectedNetwork = app.DetectDropDown.Value;
            app.type = "detect";
            app.TabGroup.SelectedTab = app.Tab2;
            
        end
        
        function TrainPushed(app)
            
            selectedDataSet = app.ChooseDatasetDropDown.Value;
            action = "train";
            data = [app.type , app.selectedNetwork , action , selectedDataSet];
            %chamar funcao
            disp(data);

        end
        
        function TestPushed(app)

            selectedDataSet = app.ChooseDatasetDropDown.Value;
            action = "test";
            data = [app.type , app.selectedNetwork , action , selectedDataSet];
            %chamar funcao
            disp(data);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.DetectPushed();
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.PredictPushed();
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainPushed();
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.TestPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosethenetworkLabel = uilabel(app.UIFigure);
            app.ChoosethenetworkLabel.Text = {'Choose the '; 'network'};
            app.ChoosethenetworkDropDown_2Label = uilabel(app.UIFigure);
            app.ChoosethenetworkDropDown_2Label.Text = {'Choose the '; 'network'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'app_image.jpg');
            app.PredictButton = uibutton(app.Tab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.SelectthenetworkDropDownLabel = uilabel(app.Tab);
            app.SelectthenetworkDropDownLabel.Text = {'Select the '; 'network'};
            app.PredictDropDown = uidropdown(app.Tab);
            app.PredictDropDown.Items = {'FTDNN'};
            app.PredictDropDown.Value = 'FTDNN';
            app.DetectButton = uibutton(app.Tab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.SelectthenetworkDropDown_2Label = uilabel(app.Tab);
            app.SelectthenetworkDropDown_2Label.Text = {'Select the '; 'network'};
            app.DetectDropDown = uidropdown(app.Tab);
            app.DetectDropDown.Items = {'FeedForward', 'CNN', 'LSTM'};
            app.DetectDropDown.Value = 'FeedForward';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image2 = uiimage(app.Tab2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'app_image.jpg');
            app.TestButton = uibutton(app.Tab2, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TrainButton = uibutton(app.Tab2, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ChooseDatasetDropDownLabel = uilabel(app.Tab2);
            app.ChooseDatasetDropDownLabel.Text = 'Choose Dataset';
            app.ChooseDatasetDropDown = uidropdown(app.Tab2);
            app.ChooseDatasetDropDown.Items = {'54802', '112502'};
            app.ChooseDatasetDropDown.Value = '54802';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end