classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        DetectDropDown                 matlab.ui.control.DropDown
        SelectthenetworkDropDown_2Label  matlab.ui.control.Label
        DetectButton                   matlab.ui.control.Button
        PredictDropDown                matlab.ui.control.DropDown
        SelectthenetworkDropDownLabel  matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Tab2                           matlab.ui.container.Tab
        UseGpu                         matlab.ui.control.CheckBox
        BalancingValue                 matlab.ui.control.CheckBox
        NumberoffeaturesLabel          matlab.ui.control.Label
        NumFeatures                    matlab.ui.control.EditField
        AutoencoderCheckBox            matlab.ui.control.CheckBox
        ChooseDatasetDropDown          matlab.ui.control.DropDown
        ChooseDatasetDropDownLabel     matlab.ui.control.Label
        TrainButton                    matlab.ui.control.Button
        TestButton                     matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ChoosethenetworkDropDown_2Label  matlab.ui.control.Label
        ChoosethenetworkLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Define properties here to store values
        selectedNetwork
        type
        GPU
    end
    
    methods (Access = private)
        
        function PredictPushed(app)

            app.selectedNetwork = app.PredictDropDown.Value;
            app.type = "predict";
            app.TabGroup.SelectedTab = app.Tab2;

        end
        
        function DetectPushed(app)

            app.selectedNetwork = app.DetectDropDown.Value;
            app.type = "detect";
            app.TabGroup.SelectedTab = app.Tab2;
            
        end
        
        function TrainPushed(app)
            
            selectedDataSet = app.ChooseDatasetDropDown.Value;
            Balancing = app.BalancingValue.Value;
            app.GPU = app.UseGpu.Value;
            action = "train";
            data = [app.type , app.selectedNetwork , action , selectedDataSet];
            disp(data);
            % Get the value from the edit text box

            if app.AutoencoderCheckBox.Value

                enteredValue = str2double(app.NumFeatures.Value);
                
                % Check if the value is between 1 and 28
                if ~isnan(enteredValue) && enteredValue >= 1 && enteredValue <= 28
                    % Value is valid, perform your actions here
           
                    % chamar funcao com enteredValue
                    [se, sp, se_5_10, sp_5_10, error_msg] = main(app.type, app.selectedNetwork, action, selectedDataSet, enteredValue, Balancing, app.GPU);

                    if error_msg ~= ""
                        uialert(app.UIFigure, error_msg, 'Input Error', 'Icon', 'error');
                    end
                else
                    % Show a notification if any of the edit text boxes are empty
                    uialert(app.UIFigure, 'Value must be between 1 and 28!', 'Input Error', 'Icon', 'error');
                end
            else
                %chamar funcao com valor 0
                [se, sp, se_5_10, sp_5_10, error_msg] = main(app.type, app.selectedNetwork, action, selectedDataSet, 0, Balancing, app.GPU);

                if error_msg ~= ""
                    uialert(app.UIFigure, error_msg, 'Input Error', 'Icon', 'error');
                end
            end
        
            % Create a new tab
            newTab = uitab(app.TabGroup, 'Title', 'Results', 'BackgroundColor', [1, 1, 1]); % Set the background color to white
            
            % Load your background image
            backgroundImage = imread('app_image.jpg'); 
            
            ax = axes('Parent', newTab, 'Position', [0, 0, 1, 1], 'Visible', 'off');
            imshow(backgroundImage, 'Parent', ax);
                        
            % Add a text label
            resultLabel = uilabel(newTab, 'Text', 'RESULTS', 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'Position', [25, 350, 600, 20]);
            resultLabel.FontColor  = [1, 1, 1]; % Set text color to white
            
            SEs = {se, se_5_10};
            SPs = {sp, sp_5_10};

             % Organize the data into a cell array for each row
            rowData = {SEs{1}, SPs{1}; SEs{2}, SPs{2}};
            
            % Create a uitable to display the results in two columns and three rows
            resultTable = uitable(newTab, 'Data', rowData, ...
                'ColumnName', {'SE', 'SP'}, ...
                'RowName', {'point-by-point', '5 out of 10'}, ...
                'Position', [25, 200, 600, 150]);
            
            % Switch to the Results tab
            app.TabGroup.SelectedTab = newTab;

        end


        function TestPushed(app)

            selectedDataSet = app.ChooseDatasetDropDown.Value;
            app.GPU = app.UseGpu.Value;
            action = "test";
            data = [app.type , app.selectedNetwork , action , selectedDataSet];

            %chamar funcao
            [se, sp, se_5_10, sp_5_10, error_msg] = main(app.type, app.selectedNetwork, action, selectedDataSet, 0, true, app.GPU);

            if error_msg ~= ""
                uialert(app.UIFigure, error_msg, 'Input Error', 'Icon', 'error');
            end

            % Create a new tab
            newTab = uitab(app.TabGroup, 'Title', 'Results', 'BackgroundColor', [1, 1, 1]); % Set the background color to white
            
            % Load your background image
            backgroundImage = imread('app_image.jpg'); 
            
            ax = axes('Parent', newTab, 'Position', [0, 0, 1, 1], 'Visible', 'off');
            imshow(backgroundImage, 'Parent', ax);
                        
            % Add a text label
            resultLabel = uilabel(newTab, 'Text', 'RESULTS', 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'Position', [25, 350, 600, 20]);
            resultLabel.FontColor  = [1, 1, 1]; % Set text color to white
            
            SEs = {se, se_5_10};
            SPs = {sp, sp_5_10};

             % Organize the data into a cell array for each row
            rowData = {SEs{1}, SPs{1}; SEs{2}, SPs{2}};
            
            % Create a uitable to display the results in two columns and three rows
            resultTable = uitable(newTab, 'Data', rowData, ...
                'ColumnName', {'SE', 'SP'}, ...
                'RowName', {'point-by-point', '5 out of 10'}, ...
                'Position', [25, 200, 600, 150]);
            
            % Switch to the Results tab
            app.TabGroup.SelectedTab = newTab;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.DetectPushed();
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.PredictPushed();
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainPushed();
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.TestPushed();
        end

        % Value changed function: AutoencoderCheckBox
        function AutoencoderCheckBoxValueChanged(app, event)
            value = app.AutoencoderCheckBox.Value;
            
            if value
                set(app.NumFeatures, 'Visible', 'on');
                set(app.NumberoffeaturesLabel, 'Visible', 'on');
            else
                set(app.NumFeatures, 'Visible', 'off');
                set(app.NumberoffeaturesLabel, 'Visible', 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosethenetworkLabel = uilabel(app.UIFigure);
            app.ChoosethenetworkLabel.Text = {'Choose the '; 'network'};
            app.ChoosethenetworkDropDown_2Label = uilabel(app.UIFigure);
            app.ChoosethenetworkDropDown_2Label.Text = {'Choose the '; 'network'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'app_image.jpg');
            app.PredictButton = uibutton(app.Tab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.SelectthenetworkDropDownLabel = uilabel(app.Tab);
            app.SelectthenetworkDropDownLabel.Text = {'Select the '; 'network'};
            app.PredictDropDown = uidropdown(app.Tab);
            app.PredictDropDown.Items = {'FFN', 'FTDNN', 'CNN', 'LSTM'};
            app.PredictDropDown.Value = 'LSTM';
            app.DetectButton = uibutton(app.Tab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.SelectthenetworkDropDown_2Label = uilabel(app.Tab);
            app.SelectthenetworkDropDown_2Label.Text = {'Select the '; 'network'};
            app.DetectDropDown = uidropdown(app.Tab);
            app.DetectDropDown.Items = {'FFN', 'FTDNN', 'CNN', 'LSTM'};
            app.DetectDropDown.Value = 'LSTM';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image2 = uiimage(app.Tab2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'app_image.jpg');
            app.TestButton = uibutton(app.Tab2, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TrainButton = uibutton(app.Tab2, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ChooseDatasetDropDownLabel = uilabel(app.Tab2);
            app.ChooseDatasetDropDownLabel.Text = 'Choose Dataset';
            app.ChooseDatasetDropDown = uidropdown(app.Tab2);
            app.ChooseDatasetDropDown.Items = {'54802', '112502'};
            app.ChooseDatasetDropDown.Value = '54802';
            app.AutoencoderCheckBox = uicheckbox(app.Tab2);
            app.AutoencoderCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoencoderCheckBoxValueChanged, true);
            app.AutoencoderCheckBox.Text = 'Auto encoder';
            app.NumFeatures = uieditfield(app.Tab2, 'text');
            app.NumFeatures.Visible = 'off';
            app.NumberoffeaturesLabel = uilabel(app.Tab2);
            app.NumberoffeaturesLabel.Visible = 'off';
            app.NumberoffeaturesLabel.Text = 'Number of features';
            app.BalancingValue = uicheckbox(app.Tab2);
            app.BalancingValue.Text = 'Balancing of seizures numbers';
            app.UseGpu = uicheckbox(app.Tab2);
            app.UseGpu.Text = 'Use GPU & CPU';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end